classdef Assignment3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CostTable                     matlab.ui.control.Table
        EpochSizeLabel                matlab.ui.control.Label
        Epoch_Size                    matlab.ui.control.NumericEditField
        IterationsLabel               matlab.ui.control.Label
        Iterations                    matlab.ui.control.NumericEditField
        LearningRateLabel             matlab.ui.control.Label
        Learning_Rate                 matlab.ui.control.NumericEditField
        Progressive_minimums          matlab.ui.control.UIAxes
        Conncection_Configuration     matlab.ui.control.UIAxes
        Connections_Per_Concentrator  matlab.ui.control.UIAxes
        GaugeLabel                    matlab.ui.control.Label
        Gauge                         matlab.ui.control.SemicircularGauge
        StartButton                   matlab.ui.control.Button
        FinalCostGaugeLabel           matlab.ui.control.Label
        FinalCostGauge                matlab.ui.control.LinearGauge
    end

    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% CREATING THE PROBABILITY VECTOR
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
PV = ones(1,36);                                                           % This gives us an array 36 1's
PV = PV*0.5;                                                               % This sets all the values to 0.5

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% THE COST TABLE
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
CostTable = xlsread('CostTable.xlsx');                                     % Imports the cost table
CostTable = CostTable(2:9,2:13);                                           % Removes row and column numbers.
app.CostTable.Data = CostTable                                             % Assign the Table viewer on the 
                                                                           % application to the Cost Table

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% USER DEFINED VARIABLES
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
Iterations = app.Iterations.Value;                                         % Assigns the Iterations Variable
                                                                           % to the input box in the application
Learning_Rate = app.Learning_Rate.Value                                    % Assigns the Learning Rate Variable
                                                                           % to the input box in the application
Num_Trials = app.Epoch_Size.Value;                                         % Assigns the Epoch Size Variable
                                                                           % to the input box in the application

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% USER DEFINED VARIABLES
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
Final_Price = NaN(Iterations,1)                                            % Stores the final price of every 
                                                                           % Iteration 
for iteration = 1:Iterations
    Epoch = rand(Num_Trials,36)>=PV;                                       % Creates Epoch. If rand >=PV
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Converting the Binary Epoch to Decimals And Storing it in the the Binary Epoch Matrixs
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    Number_Epoch = NaN(Num_Trials,12);                                     % Pre-emptied Matrix to store Values
    for Row = 1:Num_Trials                                                 % For Loop Reads the Binary Epoch Row 
                                                                           % by Row to and converts the Binarty 
                                                                           % Strings into Decimals Strings
        Number_Epoch(Row,1) = Epoch(Row,1)*4 + Epoch(Row,2)*2 + Epoch(Row,3)*1 + 1;
        Number_Epoch(Row,2) = Epoch(Row,4)*4 + Epoch(Row,5)*2 + Epoch(Row,6)*1 + 1;
        Number_Epoch(Row,3) = Epoch(Row,7)*4 + Epoch(Row,8)*2 + Epoch(Row,9)*1 + 1;
        Number_Epoch(Row,4) = Epoch(Row,10)*4 + Epoch(Row,11)*2 + Epoch(Row,12)*1 + 1;
        Number_Epoch(Row,5) = Epoch(Row,13)*4 + Epoch(Row,14)*2 + Epoch(Row,15)*1 + 1;
        Number_Epoch(Row,6) = Epoch(Row,16)*4 + Epoch(Row,17)*2 + Epoch(Row,18)*1 + 1;
        Number_Epoch(Row,7) = Epoch(Row,19)*4 + Epoch(Row,20)*2 + Epoch(Row,21)*1 + 1;
        Number_Epoch(Row,8) = Epoch(Row,22)*4 + Epoch(Row,23)*2 + Epoch(Row,24)*1 + 1;
        Number_Epoch(Row,9) = Epoch(Row,25)*4 + Epoch(Row,26)*2 + Epoch(Row,27)*1 + 1;
        Number_Epoch(Row,10) = Epoch(Row,28)*4 + Epoch(Row,29)*2 + Epoch(Row,30)*1 + 1;
        Number_Epoch(Row,11) = Epoch(Row,31)*4 + Epoch(Row,32)*2 + Epoch(Row,33)*1 + 1;
        Number_Epoch(Row,12) = Epoch(Row,34)*4 + Epoch(Row,35)*2 + Epoch(Row,36)*1 + 1;
    end                                                                    % Completes the For Loop
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Calculating the costs of the Terminal to Concentrator Connections
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
    for EpochLoop = 1:Num_Trials                                           % For Loop of Num_Trial Length
        for column = 1:12                                                  % Nested For Loop 
            Location = Number_Epoch(EpochLoop,column);                     % The Location of Cost to be read      
            Costs(EpochLoop,column) = CostTable(Location,column);          % Determines Cost based on number in Epoch
        end                                                                % Completes the For Loop
    end                                                                    % Completes the For Loop

    Cost_Sums = NaN(Num_Trials,1);                                         % Pre-emptied Cost-Sums Vector
    for p = 1:Num_Trials                                                   % For Loop Reads each row of the Cost 
        Cost_Sums(p) = sum(Costs(p,:));                                    % Matrics and Calculated the Sums
    end                                                                    % Completes the For Loop
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Applying the Contraints
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
    for row = 1:Num_Trials                                                 % Nested For Loop 
        for value = 1:8                                                    % For Loop to test the amount amount 
                                                                           % Connection per Concentrator
            ConcentratorConnections(value) = sum(Number_Epoch(row,:) == value);
            if sum(Number_Epoch(row,:) == value)>3                         % Tests if there are more the 3 
                                                                           % Connections per Concentrator
                Cost_Sums(row) = 9999;                                     % Changes the Value of the Cost 
                                                                           % Sum to 9999 if there are more
                                                                           % than 3 connections
            end                                                            % Completes the For Loop
        end                                                                % Completes the For Loop
    end                                                                    % Completes the For Loop
    
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Ranking the Epoch
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%% 

    [MinRowSum,RowNr] = min(Cost_Sums);                                    % Determines the Lowest Sum and the Row Number
    Optimum_Binary_String = Epoch(RowNr,:);                                % Takes Optimum Binary String from the Epoch
    Optimal_Decimal_String = Number_Epoch(RowNr,:);                        % Takes Decimal Binary String from the Epoch
    Final_Price(iteration) = MinRowSum;                                    % Equates the Final Price of the Loop with 
                                                                           % Minimum Costsum 
                                                                           
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    % Updating the Probability Vector
    %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
    for bbh = 1:36                                                         % For Loop To read the OPtimal PV
        if Optimum_Binary_String(1,bbh) == 1                               % Test if the PV(j) = 1 
            PV(1,bbh) = PV(1,bbh)-Learning_Rate;                           % If So then PV(j) = PV(j) - LR 
            if PV(1,bbh) < 0                                               % Tests if there are any negative Values
                PV(1,bbh) = 0;                                             % If so, then PV(j) = 0 
            end                                                            % Completes the If Statement
        elseif Optimum_Binary_String(1,bbh) == 0                           % Test if the PV(j) = 1 
            PV(1,bbh) = PV(1,bbh)+Learning_Rate;                           % If So then PV(j) = PV(j) + LR 
            if PV(1,bbh) > 1                                               % Tests if there are any Values > 1
                 PV(1,bbh) = 1;                                            % If so, then PV(j) = 1 
            end                                                            % Completes the For Loop
        end                                                                % Completes the For Loop
    end                                                                    % Completes the For Loop
end                                                                        % Completes the For Loop

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% PRESENTING THE GRAPHS AND GAUGES IN THE APP 
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
plot(app.Progressive_minimums,Final_Price)                                 % Plots the Progressive Minimums
bar(app.Conncection_Configuration, Optimal_Decimal_String)                 % Plots the Connection Configuration
barh(app.Connections_Per_Concentrator,ConcentratorConnections)             % Plots the Connection per Concentrator
app.FinalCostGauge.Value = MinRowSum                                       % Shows Final Cost on Linear Guage
app.Gauge.Value=MinRowSum;                                                 % Shows Final Cost on Radial Guage
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.CostTable = uitable(app.UIFigure);
            app.CostTable.ColumnName = {'Terminal 1'; 'Terminal 2'; 'Terminal 3'; 'Terminal 4'; 'Terminal 5'; 'Terminal 6'; 'Terminal 7'; 'Terminal 8'; 'Terminal 9'; 'Terminal 10'; 'Terminal 11'; 'Terminal 12'; ''};
            app.CostTable.RowName = {};
            app.EpochSizeLabel = uilabel(app.UIFigure);
            app.EpochSizeLabel.Text = 'Epoch Size';
            app.Epoch_Size = uieditfield(app.UIFigure, 'numeric');
            app.IterationsLabel = uilabel(app.UIFigure);
            app.IterationsLabel.Text = 'Iterations';
            app.Iterations = uieditfield(app.UIFigure, 'numeric');
            app.LearningRateLabel = uilabel(app.UIFigure);
            app.LearningRateLabel.Text = 'Learning Rate';
            app.Learning_Rate = uieditfield(app.UIFigure, 'numeric');
            app.Progressive_minimums = uiaxes(app.UIFigure);
            title(app.Progressive_minimums, 'Progressive Minimums')
            xlabel(app.Progressive_minimums, 'Iteration')
            ylabel(app.Progressive_minimums, 'MInumum Cost')
            app.Conncection_Configuration = uiaxes(app.UIFigure);
            title(app.Conncection_Configuration, 'Connection Configuration')
            xlabel(app.Conncection_Configuration, 'Terminal')
            ylabel(app.Conncection_Configuration, 'Concentrator')
            app.Connections_Per_Concentrator = uiaxes(app.UIFigure);
            title(app.Connections_Per_Concentrator, 'Connections Per Concentrator')
            xlabel(app.Connections_Per_Concentrator, 'Connections')
            ylabel(app.Connections_Per_Concentrator, 'Concentrator')
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'semicircular');
            app.Gauge.Limits = [100 300];
            app.Gauge.ScaleDirection = 'counterclockwise';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.FinalCostGaugeLabel = uilabel(app.UIFigure);
            app.FinalCostGaugeLabel.Text = 'Final Cost';
            app.FinalCostGauge = uigauge(app.UIFigure, 'linear');
            app.FinalCostGauge.Limits = [120 180];
        end
    end

    methods (Access = public)

        % Construct app
        function app = Assignment3

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end